classdef glove_defect_detection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        ExitButton       matlab.ui.control.Button
        ResultsLabel     matlab.ui.control.Label
        uipanel1         matlab.ui.container.Panel
        ResetButton      matlab.ui.control.Button
        DetectButton     matlab.ui.control.Button
        LoadImageButton  matlab.ui.control.Button
        txtGloveTitle    matlab.ui.control.Label
        AxesGloveImage   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function glove_defect_detection_OpeningFcn(app, varargin)
            % --- Executes just before glove_defect_detection is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to glove_defect_detection (see VARARGIN)
            
            % Choose default command line output for glove_defect_detection
            handles.output = hObject;

            %to set axes handles not to show ticks on axes
            axes1(handles.InputAxes);
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: DetectButton
        function DetectButton_Callback(app, event)
            % --- Executes on button press in btnDetect.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnDetect (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            Img = handles.Img;
            
            % Check for Bottle
            I = imcrop(Img,[115 0 110 352]); % Crop the region for Inspection
            
            Find_Glove = FindArea(I,3000,'N');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButton_Callback(app, event)
            % --- Executes on button press in btnLoadImage.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to btnLoadImage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Function to Get Image
            [filename, pathname] = uigetfile({'*.jpg;*.tif;*.bmp;*.jpeg;*.png;*.gif','All Image Files';'*.*','All Files'}, 'Select an Image');
            if isnumeric(filename):
                return;
            end
            
            fileName = fullfile(pathname, filename);
            
            
            % Read Image
            Img = imread(fileName);
            
            % Display Image
            axes1(handles.InputAxes);
            imshow(Img);
            
            handles.Img = Img;
            guidata(hObject, handles)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'glove_defect_detection';
            app.figure1.Tag = 'figure1';
            app.AxesGloveImage = uiaxes(app.figure1);
            title(app.AxesGloveImage, 'Glove Image Display')
            xlabel(app.AxesGloveImage, 'X')
            ylabel(app.AxesGloveImage, 'Y')
            zlabel(app.AxesGloveImage, 'Z')
            app.txtGloveTitle = uilabel(app.figure1);
            app.txtGloveTitle.Tag = 'txtGloveTitle';
            app.txtGloveTitle.Text = 'Glove Defect Detection';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.LoadImageButton = uibutton(app.uipanel1, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_Callback, true);
            app.LoadImageButton.Tag = 'btnLoadImage';
            app.LoadImageButton.Text = 'Load Image';
            app.DetectButton = uibutton(app.uipanel1, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButton_Callback, true);
            app.DetectButton.Tag = 'btnDetect';
            app.DetectButton.Text = 'Detect Defect';
            app.ResetButton = uibutton(app.uipanel1, 'push');
            app.ResetButton.Tag = 'btnReset';
            app.ResetButton.Text = 'Reset';
            app.ResultsLabel = uilabel(app.figure1);
            app.ResultsLabel.Text = 'Results';
            app.ExitButton = uibutton(app.figure1, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = glove_defect_detection_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)glove_defect_detection_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end